classdef Sistem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        UploadButton       matlab.ui.control.Button
        DeteksiButton      matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        ResetButton        matlab.ui.control.Button
        DeteksiCitraPenyakitPisangLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        UIAxes_2           matlab.ui.control.UIAxes
        HasilDeteksiLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        gambar;
        gambarex;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {408, 408};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {384, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DeteksiButton
        function DeteksiButtonPushed(app, event)
        
         
            citra = string(app.gambar);
          
                gambarp = imread(app.gambar);
                
    Img = rgb2gray(gambarp);
    H = imhist(Img)';
    H = H/sum(H);
    I = [0:255];
    CiriMEAN = I*H';
    CiriENT = -H*log2(H+eps)';
    CiriVAR = (I-CiriMEAN).^2*H';
    CiriSKEW = (I-CiriMEAN).^3*H'/CiriVAR^1.5;
    CiriKURT = (I-CiriMEAN).^4*H'/CiriVAR^2-3;
    nmfeature=[CiriMEAN,CiriENT,CiriVAR,CiriSKEW,CiriKURT];
                
                
                Model = loadLearnerForCoder('SVMleaf');
                label = predict(Model,nmfeature);
                label = string(label);
                channels = 175;
              
        
                app.gambarex = Img;
                imshow(app.gambarex,'Parent',app.UIAxes_2)
                app.Label.Visible = 'on';
                app.Label.Text = label;


        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
        [FileName, PathName] = uigetfile({'*.png*'},'Select a image file');
        image = strcat(PathName,FileName);
        app.gambar = image;
        imshow(image,'Parent',app.UIAxes)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
         
        
            app.UIAxes.cla;
            app.UIAxes_2.cla;
            app.Label.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.DeteksiButton = uibutton(app.LeftPanel, 'push');
            app.DeteksiButton.ButtonPushedFcn = createCallbackFcn(app, @DeteksiButtonPushed, true);
            app.DeteksiButton.Text = 'Deteksi';
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.Visible = 'off';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DeteksiCitraPenyakitPisangLabel = uilabel(app.LeftPanel);
            app.DeteksiCitraPenyakitPisangLabel.Text = 'Deteksi Citra Penyakit Pisang';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.Visible = 'off';
            app.HasilDeteksiLabel = uilabel(app.RightPanel);
            app.HasilDeteksiLabel.Text = 'Hasil Deteksi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sistem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end